classdef CyBotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        VictoryRoyaleButton     matlab.ui.control.Button
        FastScanButton          matlab.ui.control.Button
        PointButton             matlab.ui.control.Button
        CyBotResponseEditField  matlab.ui.control.EditField
        CyBotResponseLabel      matlab.ui.control.Label
        TurnRightSmall          matlab.ui.control.Button
        TurnLeftSmall           matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        MoveBackwardLarge       matlab.ui.control.Button
        MoveBackwardSmall       matlab.ui.control.Button
        Scan                    matlab.ui.control.Button
        TurnRightLarge          matlab.ui.control.Button
        TurnLeftLarge           matlab.ui.control.Button
        MoveForwardSmall        matlab.ui.control.Button
        MoveForwardLarge        matlab.ui.control.Button
        Connect                 matlab.ui.control.Button
    end

% Sullivan Fair / Aidan Gull
% April 20, 2023
%
% GUI used to control the functions of the CyBOT used in our final project.
% Team 10D
    properties (Access = private)
        client
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            app.client = tcpclient("192.168.1.1", 288, "Timeout", 120);
            write(app.client, 'c');
        end

        % Button pushed function: Scan
        function ScanButtonPushed(app, event)
           write(app.client, 'f', "char");
           prompt = {'Enter Scan Width'};
           dlgtitle = 'Scan';
           definput = {''};
           opts.Interpreter = 'tex';
           scanRad = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
           scan = scanRad{1};
           write(app.client, scan, "char");

           row = 1;
           data = zeros(180, 2);
           while true
               tempStr = "";
               while true
                    tempChar = char(read(app.client, 1, "char"));                    
                    if(tempChar == 'n' || tempChar == 'x')
                        break;
                    end
                    tempStr = append(tempStr, tempChar);
               end
               if(tempChar == 'x')
                   break;
               end
               tempStr = str2double(tempStr);
               data(row, 2) = tempStr;
               row = row + 1;
          end
          
          row = 1;
          while true
              tempStr = "";
              while true
                    tempChar = char(read(app.client, 1, "char"));
                    if(tempChar == 'n' || tempChar == 'x')
                        break;
                    end
                    tempStr = append(tempStr, tempChar);
               end
               if(tempChar == 'x')
                   break;
               end
               tempStr = str2num(tempStr);
               data(row, 1) = deg2rad(tempStr);
               row = row + 1;
          end
          disp(data);
          dataX = data(:, 1);
          dataX = dataX.';
          dataY = data(:, 2);
          dataY = dataY.';
          sz = 25;
          polarscatter(dataX, dataY, sz, "filled");
        end

        % Button pushed function: MoveForwardLarge
        function MoveForwardLargeButtonPushed(app, event)
            write(app.client, 'W', "char");
            prompt = {'Enter Distance (cm) (Will be multiplied by 10)'};
            dlgtitle = 'Move Forward (cm)';
            definput = {''};
            opts.Interpreter = 'tex';
            distance1 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            move = distance1{1};
            write(app.client, move, "char");
       
            tempStr = "";
            while true
                tempChar = char(read(app.client, 1, "char"));
                if(tempChar == 'x')
                    break;
                end
                tempStr = append(tempStr, tempChar);
            end

            app.CyBotResponseEditField.Value = tempStr;
        end

        % Button pushed function: MoveForwardSmall
        function MoveForwardSmallButtonPushed(app, event)
            write(app.client, 'w', "char");
            prompt = {'Enter Distance (cm)'};
            dlgtitle = 'Move Forward (cm)';
            definput = {''};
            opts.Interpreter = 'tex';
            distance2 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            move = distance2{1};
            write(app.client, move, "char");
        end

        % Button pushed function: TurnLeftLarge
        function TurnLeftLargeButtonPushed(app, event)
            write(app.client, 'A', "char");
            prompt = {'Enter Degrees (Will be multiplied by 10)'};
            dlgtitle = 'Turn Left';
            definput = {''};
            opts.Interpreter = 'tex';
            degrees1 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            turn = degrees1{1};
            write(app.client, turn, "char");
            tempStr = "";

            while true
                  tempChar = char(read(app.client, 1, "char"));
                  if(tempChar == 'x')
                      break;
                  end
                  tempStr = append(tempStr, tempChar);
            end
            
            app.CyBotResponseEditField.Value = tempStr;
        end

        % Button pushed function: TurnRightLarge
        function TurnRightLargeButtonPushed(app, event)
            write(app.client, 'D', "char");
            prompt = {'Enter Degrees (Will be multiplied by 10)'};
            dlgtitle = 'Turn Right';
            definput = {''};
            opts.Interpreter = 'tex';
            degrees2 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            turn = degrees2{1};
            write(app.client, turn, "char");

            tempStr = "";
            while true
                  tempChar = char(read(app.client, 1, "char"));
                  if(tempChar == 'x')
                      break;
                  end
                  tempStr = append(tempStr, tempChar);
            end

            app.CyBotResponseEditField.Value = tempStr;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            write(app.client, 'p', "char");
            clear app.client;
        end

        % Button pushed function: MoveBackwardSmall
        function MoveBackwardSmallButtonPushed(app, event)
            write(app.client, 's', "char");
            prompt = {'Enter Distance (cm)'};
            dlgtitle = 'Move Forward (cm)';
            definput = {''};
            opts.Interpreter = 'tex';
            distance3 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            move = distance3{1};
            write(app.client, move, "char");
        end

        % Button pushed function: MoveBackwardLarge
        function MoveBackwardLargeButtonPushed(app, event)
            write(app.client, 'S', "char");
            prompt = {'Enter Distance (cm) (Will be multiplied by 10)'};
            dlgtitle = 'Move Forward (cm)';
            definput = {''};
            opts.Interpreter = 'tex';
            distance4 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            move = distance4{1};
            write(app.client, move, "char");

            tempStr = "";
            while true
                tempChar = char(read(app.client, 1, "char"));
                if(tempChar == 'x')
                    break;
                end
                tempStr = append(tempStr, tempChar);
            end

            app.CyBotResponseEditField.Value = tempStr;
        end

        % Button pushed function: TurnLeftSmall
        function TurnLeftSmallButtonPushed(app, event)
            write(app.client, 'a', "char");
            prompt = {'Enter Degrees'};
            dlgtitle = 'Turn Right';
            definput = {''};
            opts.Interpreter = 'tex';
            degrees3 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            turn = degrees3{1};
            write(app.client, turn, "char");
        end

        % Button pushed function: TurnRightSmall
        function TurnRightSmallButtonPushed(app, event)
            write(app.client, 'd', "char");
            prompt = {'Enter Degrees (Will be multiplied by 10)'};
            dlgtitle = 'Turn Right';
            definput = {''};
            opts.Interpreter = 'tex';
            degrees4 = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            turn = degrees4{1};
            write(app.client, turn, "char");
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
            write(app.client, 'g', "char");
            prompt = {'Enter angle (11-99)'};
            dlgtitle = 'Point';
            definput = {''};
            opts.Interpreter = 'tex';
            pointDir = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
            point1 = pointDir{1};
            write(app.client, point1, "char");
 
            tempStr = "";
            while true
                tempChar = char(read(app.client, 1, "char"));
                if(tempChar == 'x')
                    break;
                end
                tempStr = append(tempStr, tempChar);
            end

            app.CyBotResponseEditField.Value = tempStr;
        end

        % Button pushed function: FastScanButton
        function FastScanButtonPushed(app, event)
           write(app.client, 'F', "char");

           prompt = {'Enter Scan Width'};
           dlgtitle = 'Scan';
           definput = {''};
           opts.Interpreter = 'tex';
           scanRad = inputdlg(prompt, dlgtitle, [1, 40], definput, opts);
           scan = scanRad{1};
           write(app.client, scan, "char");

           row = 1;
           data = zeros(180, 2);
           while true
               tempStr = "";
               while true
                    tempChar = char(read(app.client, 1, "char"));                    
                    if(tempChar == 'n' || tempChar == 'x')
                        break;
                    end
                    tempStr = append(tempStr, tempChar);
               end
               if(tempChar == 'x')
                   break;
               end
               tempStr = str2double(tempStr);
               data(row, 2) = tempStr;
               row = row + 1;
          end
          
          row = 1;
          while true
              tempStr = "";
              while true
                    tempChar = char(read(app.client, 1, "char"));
                    if(tempChar == 'n' || tempChar == 'x')
                        break;
                    end
                    tempStr = append(tempStr, tempChar);
               end
               if(tempChar == 'x')
                   break;
               end
               tempStr = str2num(tempStr);
               data(row, 1) = deg2rad(tempStr);
               row = row + 1;
          end
          disp(data);
          dataX = data(:, 1);
          dataX = dataX.';
          dataY = data(:, 2);
          dataY = dataY.';
          sz = 25;
          polarscatter(dataX, dataY, sz, "filled");
        end

        % Button pushed function: VictoryRoyaleButton
        function VictoryRoyaleButtonPushed(app, event)
            write(app.client, "j", "char");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Connect = uibutton(app.UIFigure, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Text = 'Connect';
            app.MoveForwardLarge = uibutton(app.UIFigure, 'push');
            app.MoveForwardLarge.ButtonPushedFcn = createCallbackFcn(app, @MoveForwardLargeButtonPushed, true);
            app.MoveForwardLarge.Text = {'^'; '^'};
            app.MoveForwardSmall = uibutton(app.UIFigure, 'push');
            app.MoveForwardSmall.ButtonPushedFcn = createCallbackFcn(app, @MoveForwardSmallButtonPushed, true);
            app.MoveForwardSmall.Text = '^';
            app.TurnLeftLarge = uibutton(app.UIFigure, 'push');
            app.TurnLeftLarge.ButtonPushedFcn = createCallbackFcn(app, @TurnLeftLargeButtonPushed, true);
            app.TurnLeftLarge.Text = '< <';
            app.TurnRightLarge = uibutton(app.UIFigure, 'push');
            app.TurnRightLarge.ButtonPushedFcn = createCallbackFcn(app, @TurnRightLargeButtonPushed, true);
            app.TurnRightLarge.Text = '> >';
            app.Scan = uibutton(app.UIFigure, 'push');
            app.Scan.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.Scan.Text = 'Slow Scan';
            app.MoveBackwardSmall = uibutton(app.UIFigure, 'push');
            app.MoveBackwardSmall.ButtonPushedFcn = createCallbackFcn(app, @MoveBackwardSmallButtonPushed, true);
            app.MoveBackwardSmall.Text = 'v';
            app.MoveBackwardLarge = uibutton(app.UIFigure, 'push');
            app.MoveBackwardLarge.ButtonPushedFcn = createCallbackFcn(app, @MoveBackwardLargeButtonPushed, true);
            app.MoveBackwardLarge.Text = {'v'; 'v'};
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TurnLeftSmall = uibutton(app.UIFigure, 'push');
            app.TurnLeftSmall.ButtonPushedFcn = createCallbackFcn(app, @TurnLeftSmallButtonPushed, true);
            app.TurnLeftSmall.Text = '<';
            app.TurnRightSmall = uibutton(app.UIFigure, 'push');
            app.TurnRightSmall.ButtonPushedFcn = createCallbackFcn(app, @TurnRightSmallButtonPushed, true);
            app.TurnRightSmall.Text = '>';
            app.CyBotResponseLabel = uilabel(app.UIFigure);
            app.CyBotResponseLabel.Text = 'CyBot Response';
            app.CyBotResponseEditField = uieditfield(app.UIFigure, 'text');
            app.PointButton = uibutton(app.UIFigure, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Text = 'Point';
            app.FastScanButton = uibutton(app.UIFigure, 'push');
            app.FastScanButton.ButtonPushedFcn = createCallbackFcn(app, @FastScanButtonPushed, true);
            app.FastScanButton.Text = 'Fast Scan';
            app.VictoryRoyaleButton = uibutton(app.UIFigure, 'push');
            app.VictoryRoyaleButton.ButtonPushedFcn = createCallbackFcn(app, @VictoryRoyaleButtonPushed, true);
            app.VictoryRoyaleButton.Text = {'Victory'; 'Royale'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CyBotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end